classdef CRLB < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EXITButton                   matlab.ui.control.Button
        backgroundphotonsEditField   matlab.ui.control.NumericEditField
        backgroundphotonsEditFieldLabel  matlab.ui.control.Label
        signalphotonsEditField       matlab.ui.control.NumericEditField
        signalphotonsEditFieldLabel  matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        cam %camera class
        PSF %PSF class
        z_axis % Description
    end
    
    methods (Access = private)
        
        function plot_precisions(app, N, bg)

            [Cx,Cy,Cz,~,~,~] = fun_CRLB(app.PSF, N, bg, app.cam); 
            plot(app.UIAxes, repmat(app.z_axis', 1, 3), [sqrt(Cx)', sqrt(Cy)', sqrt(Cz)']); 
            legend(app.UIAxes, ["\sigma_x", "\sigma_y", "\sigma_z"])
            grid(app.UIAxes, "on");             

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, PSF, cam)
            app.cam = cam; 
            app.PSF = PSF;
            N = app.signalphotonsEditField.Value;
            bg = app.backgroundphotonsEditField.Value; 
            app.z_axis = (0:(size(app.PSF.data,3)-1))*app.PSF.uz*1e9;
            plot_precisions(app, N, bg);

        end

        % Button pushed function: EXITButton
        function EXITButtonPushed(app, event)
            app.delete
        end

        % Value changed function: signalphotonsEditField
        function signalphotonsEditFieldValueChanged(app, event)
            N = app.signalphotonsEditField.Value;
            bg = app.backgroundphotonsEditField.Value; 
            app.z_axis = (0:(size(app.PSF.data,3)-1))*app.PSF.uz*1e9;
            plot_precisions(app, N, bg);
        end

        % Value changed function: backgroundphotonsEditField
        function backgroundphotonsEditFieldValueChanged(app, event)
            bg = app.backgroundphotonsEditField.Value;
            N = app.signalphotonsEditField.Value;
            app.z_axis = (0:(size(app.PSF.data,3)-1))*app.PSF.uz*1e9;
            plot_precisions(app, N, bg);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Localization precision')
            xlabel(app.UIAxes, 'z / nm')
            ylabel(app.UIAxes, '\sigma  /  nm')
            zlabel(app.UIAxes, 'Z')
            app.signalphotonsEditFieldLabel = uilabel(app.UIFigure);
            app.signalphotonsEditFieldLabel.Text = 'signal / photons';
            app.signalphotonsEditField = uieditfield(app.UIFigure, 'numeric');
            app.signalphotonsEditField.ValueChangedFcn = createCallbackFcn(app, @signalphotonsEditFieldValueChanged, true);
            app.signalphotonsEditField.Value = 2000;
            app.backgroundphotonsEditFieldLabel = uilabel(app.UIFigure);
            app.backgroundphotonsEditFieldLabel.Text = 'background / photons';
            app.backgroundphotonsEditField = uieditfield(app.UIFigure, 'numeric');
            app.backgroundphotonsEditField.ValueChangedFcn = createCallbackFcn(app, @backgroundphotonsEditFieldValueChanged, true);
            app.backgroundphotonsEditField.Value = 100;
            app.EXITButton = uibutton(app.UIFigure, 'push');
            app.EXITButton.ButtonPushedFcn = createCallbackFcn(app, @EXITButtonPushed, true);
            app.EXITButton.Text = 'EXIT';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CRLB(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end